classdef Appdesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ControlPanel              matlab.ui.container.Panel
        RecordButton              matlab.ui.control.Button
        PlotButton                matlab.ui.control.Button
        SpectrumButton            matlab.ui.control.Button
        Load                      matlab.ui.control.Button
        MFCC                      matlab.ui.control.Button
        PreemphCheckBox           matlab.ui.control.CheckBox
        ClearButton               matlab.ui.control.Button
        Endcut                    matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        SpeakerIDEditFieldLabel   matlab.ui.control.Label
        SpeakerIDEditField        matlab.ui.control.NumericEditField
        PlayBackPanel             matlab.ui.container.Panel
        Sound                     matlab.ui.control.Button
        Excel                     matlab.ui.control.Button
        Label_2                   matlab.ui.control.Label
        IdentificationPanel       matlab.ui.container.Panel
        Train                     matlab.ui.control.Button
        MultiTest                 matlab.ui.control.Button
        Save                      matlab.ui.control.Button
        SingleTest                matlab.ui.control.Button
        PasswordDetect            matlab.ui.control.Button
        CentroidEditFieldLabel    matlab.ui.control.Label
        CentroidEditField         matlab.ui.control.NumericEditField
        Label_3                   matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        NofSpeakerEditFieldLabel  matlab.ui.control.Label
        NofSpeakerEditField       matlab.ui.control.NumericEditField
        Lamp                      matlab.ui.control.Lamp
        Label_Lamp                matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        PasswordHTML              matlab.ui.control.HTML
        password_label            matlab.ui.control.Label
    end

    
    properties (Access = private)
        myVoice
        Fs% Description
        myVoice_preem % Description
        SpeakerID % Description
        ceptrum % Description
        codebook % Description
        
        trainpath % Description
        testpath % Description
        NumberofSpearker % Description
        centroid % Description
        Number % Description
        distance2excel % Description
        Excelpath % Description
        word % Description
        trainpasswordpath % Description
        codebookpass % Description
        password
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %cd C:\Users\Hi\Desktop\LVTN\GUI_matlab\;
            app.trainpath =         "C:\Users\Hi\Desktop\LVTN\GUI_matlab\train_20_final\1\";
            %             app.trainpasswordpath = "C:\Users\Hi\Desktop\LVTN\GUI_matlab\Password1\data\Password\";
            app.trainpasswordpath = "C:\Users\Hi\Desktop\LVTN\GUI_matlab\Password1\data\Password3\";
            app.testpath  =         "C:\Users\Hi\Desktop\LVTN\GUI_matlab\test\";
            app.Excelpath =         "C:\Users\Hi\Desktop\LVTN\document\data.xlsx";
            %Turn off lamp
            app.Lamp.Color = [0.90,0.90,0.90];
            %             app.Label_Lamp.Text = "Not Active";
            
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            Fs = 16000 ;
            nBits = 8 ;
            nChannels = 1 ;
            % default audio input device
            recObj = audiorecorder(Fs,nBits,nChannels,0);
            %display
            app.Label_2.Text = sprintf('s%d.wav',app.SpeakerID);
            app.Label.Text = "Start of recording after 3 ...";
            pause(1);
            app.Label.Text = "Start of recording after 2 ...";
            pause(1);
            app.Label.Text = "Start of recording after 1 ...";
            pause(1);
            app.Label.Text = "Start of Recording";
            recordblocking(recObj,2);
            app.Label.Text = "End of Recording";
            pause(1);
            %record
            y  = getaudiodata(recObj);
            audio_play = audioplayer(y,Fs);
            %playback
            sound(y,Fs);
            %save to folder
            audiopath = ("C:\Users\Hi\Desktop\LVTN\GUI_matlab\Audio\password\");
            savepath = sprintf("%ss%d.wav",audiopath,app.SpeakerID);
            audiowrite(savepath,y,Fs);
            app.Label.Text = "Saved voice";
            
            app.myVoice = y;
            app.Fs      = Fs;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            close all;
            plot(app.UIAxes,app.myVoice,'r');
            app.Label.Text = string(app.Fs);
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            [m,f] = spectrum(app.myVoice,app.Fs);
            plot(app.UIAxes,f,m);
            xlabel(app.UIAxes,'f (Hz)');
            title(app.UIAxes,'Spectrum');
        end

        % Value changed function: PreemphCheckBox
        function PreemphCheckBoxValueChanged(app, event)
            value = app.PreemphCheckBox.Value;
            close all;
            myVoice_preemph = pre_emph(app.myVoice);
            app.myVoice_preem = myVoice_preemph;
            title(app.UIAxes,'');
            if value == true
                close all;
                plot(app.UIAxes,myVoice_preemph,'r');
                hold (app.UIAxes,'on');
                plot(app.UIAxes,app.myVoice,'b');
                hLegend = legend(app.UIAxes,"Preemphasis","Original");
                
            else
                close all;
                legend(app.UIAxes,'off');
                plot(app.UIAxes,app.myVoice,'b');
                
                
            end
            hold (app.UIAxes,'off');
            app.myVoice = myVoice_preemph;
        end

        % Value changed function: SpeakerIDEditField
        function SpeakerIDEditFieldValueChanged(app, event)
            value = app.SpeakerIDEditField.Value;
            app.SpeakerID = value;
        end

        % Button pushed function: MFCC
        function MFCCButtonPushed(app, event)
            
            close all;
            
            s = app.myVoice;
            s(s==0)=[];
            s=pre_emph(s);
            s=s/max(abs(s));
            
            app.ceptrum = mymfcc(s,app.Fs);
            plot(app.UIAxes,app.ceptrum);
            app.UIAxes.Title.String = ("Accounstic vector");
        end

        % Button pushed function: Sound
        function SoundButtonPushed(app, event)
            sound(app.myVoice,app.Fs);
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            
            [file,~] = uigetfile('*.wav','Select your audio');
            [app.myVoice,app.Fs] = audioread(file);
            drawnow;
            figure(app.UIFigure);
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Button pushed function: Train
        function TrainButtonPushed(app, event)
            
            %             k=app.CentroidEditField.Value;
            %             k=k*2;
            %             codebook = vqlbg(app.ceptrum,k,1);
            %             close all;
            %             app.codebook = codebook;
            %             plot(app.UIAxes,codebook);
            %             app.UIAxes.Title.String = ("Accounstic vector after VQ");
            %             filename = "database.mat";
            %             save(filename);
            n = app.NumberofSpearker;
            
            k = app.centroid;                         % number of centroids required
            %k=k*2;
            for i = 1:n                  % train a VQ codebook for each speaker
                file = sprintf('%ss%d_1.wav', app.trainpath, i);
                %sprintf(file);
                app.TextArea.Value = [string(app.TextArea.Value);"Create codebook for each audio in train folder:";file];
                
                [s, fs] = audioread(file);
                s(s==0)=[];
                s = pre_emph(s);
                s=s/max(abs(s));
                
                v = mymfcc(s, fs);            % Compute MFCC'
                code{i} = vqlbg(v, k,2);      % Train VQ codebook
            end
            
            app.codebook = code;
            
        end

        % Value changed function: CentroidEditField
        function CentroidEditFieldValueChanged(app, event)
            value = app.CentroidEditField.Value;
            app.centroid = value;
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            %              trainpath = ("C:\Users\Hi\Desktop\LVTN\GUI_matlab\train\");
            file = sprintf('%ss%d.wav', app.trainpath, app.SpeakerID);
            audiowrite(file,app.myVoice,app.Fs);
            app.TextArea.Value = [string(app.TextArea.Value);"Create file audio in train folder:";file];
        end

        % Button pushed function: MultiTest
        function MultiTestButtonPushed(app, event)
            n = app.NumberofSpearker;
            code =app.codebook;
            
            for k = 1:n                     % read test sound file of each speaker
                file = sprintf('%ss%d.wav', app.testpath, k);
                [s, fs] = audioread(file);
                s(s==0)=[];
                v = mymfcc(s, fs);            % Compute MFCC's
                
                distmin = inf;
                k1 = 0;
                
                for l = 1:length(code)      % each trained codebook, compute distortion
                    d = disteu(v, code{l},2);
                    dist = sum(min(d,[],2)) / size(d,1);
                    
                    if dist < distmin
                        distmin = dist;
                        k1 = l;
                    end
                end
                
                msg = sprintf('Speaker %d matches with speaker %d', k, k1);
                app.TextArea.Value = [string(app.TextArea.Value);msg];
            end
            
        end

        % Button pushed function: SingleTest
        function SingleTestButtonPushed(app, event)
            s = app.myVoice;
            fs =app.Fs;
            v = app.ceptrum;
            code = app.codebook;
            n = app.NumberofSpearker;
            %     load("database.dat","-mat");
            
            
            distmin = inf;
            k1 = 0;
            
            for l = 1:length(code)      % each trained codebook, compute distortion
                d = disteu(v, code{l},2);
                dist = sum(min(d,[],2)) / size(d,1);
                
                app.distance2excel(l) = dist;
                
                file = sprintf('%ss%d_5.wav', app.trainpath, l);
                app.TextArea.Value = [string(app.TextArea.Value);file;"Distance:";num2str(dist)];
                
                
                if dist < distmin
                    distmin = dist;
                    k1 = l;
                    
                end
                
            end
            
            
            %    end
            message = sprintf("Matches with speaker %d",k1);
            app.TextArea.Value = [string(app.TextArea.Value);message];
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TextArea.Value = '';
        end

        % Value changed function: NofSpeakerEditField
        function NofSpeakerEditFieldValueChanged(app, event)
            value = app.NofSpeakerEditField.Value;
            app.NumberofSpearker =value;
        end

        % Callback function
        function NumberEditFieldValueChanged(app, event)
            value = app.NumberEditField.Value;
            app.Number = value;
            
            
        end

        % Button pushed function: Excel
        function ExcelButtonPushed(app, event)
            Number_of_sheet = 1;
            Begin_range      = 'C3';
            
            writematrix(app.distance2excel,app.Excelpath,'sheet',Number_of_sheet,'Range',Begin_range);
            
            message = sprintf("Write to Excel %s, sheet: %d,begin address: %s",app.Excelpath,Number_of_sheet,Begin_range);
            app.TextArea.Value = [string(app.TextArea.Value);message];
        end

        % Button pushed function: Endcut
        function EndcutButtonPushed(app, event)
            %% caculate endpoint
            x = app.myVoice; fs=app.Fs;                 % Read data file
            x=x/max(abs(x));                        %  Amplitude normalization
            N=length(x);                            %  Take the signal length
            time=(0:N-1)/fs;                        %  calculating time
            figure;
            subplot 311
            plot(time,x,'k');
            title('Endpoint Detection of Double Threshold Method');
            ylabel('Amplitude'); axis([0 max(time) -1 1]);
            xlabel('Time/s');
            
            wlen=256; inc=90;                       %  Framing parameters
            IS=0.1; overlap=wlen-inc;               %  Set upIS
            NIS=fix((IS*fs-wlen)/inc +1);           %  CalculationNIS
            fn=fix((N-wlen)/inc)+1;                 %  Find the number of frames
            frameTime=FrameTimeC(fn, wlen, inc, fs);%  Calculate the time corresponding to each frame
            
            [voiceseg,vsl,~,~,amp,zcr]=vad_ezm1(x,wlen,inc,NIS);  %  Endpoint detection
            
            %% plot
            subplot 312
            plot(frameTime,amp,'k');
            ylim([min(amp) max(amp)])
            title('Short-term energy');
            ylabel('Amplitude');
            xlabel('Time/s');
            subplot 313
            plot(frameTime,zcr,'k');
            ylim([min(zcr) max(zcr)])
            title('Short time zero crossing rate');
            ylabel('Amplitude');
            xlabel('Time/s');
            
            
            % % caculate matrix for word
            % for j= 1 :vsl
            %     nx1=voiceseg(j).begin;
            %     nx2=voiceseg(j).end;
            %     nxl=voiceseg(j).duration;
            %
            %     s1 = frameTime(nx1)*fs; %begin in sample
            %     s2 = frameTime(nx2)*fs; %end in sample
            %     s3 = frameTime(nxl)*fs; %duration in sample
            %
            %     a(j) = (floor(s2-s1)+1);
            %     disp(a);
            %
            % end
            % % create word matrix
            %     max_voiceseg = max(a,[],'all');
            %     disp (max_voiceseg);
            %     word = zeros(max_voiceseg,vsl);
            
            for k=1 : vsl                           %  Draw the starting and ending points
                
                subplot 311
                
                %caculate for plotting in time
                nx1=voiceseg(k).begin;
                nx2=voiceseg(k).end;
                nxl=voiceseg(k).duration;
                
                %caculate for sample
                s1 = frameTime(nx1)*fs; %begin in sample
                s2 = frameTime(nx2)*fs; %end in sample
                s3 = frameTime(nxl)*fs; %duration in sample
                
                
                % plot audio
                line([frameTime(nx1) frameTime(nx1)],[-1.5 1.5],'color','r','LineStyle','-');
                line([frameTime(nx2) frameTime(nx2)],[-1.5 1.5],'color','b','LineStyle','--');
                subplot 312
                line([frameTime(nx1) frameTime(nx1)],[min(amp) max(amp)],'color','r','LineStyle','-');
                line([frameTime(nx2) frameTime(nx2)],[min(amp) max(amp)],'color','b','LineStyle','--');
                subplot 313
                line([frameTime(nx1) frameTime(nx1)],[min(zcr) max(zcr)],'color','r','LineStyle','-');
                line([frameTime(nx2) frameTime(nx2)],[min(zcr) max(zcr)],'color','b','LineStyle','--');
                
                word{k} = x(s1:s2);
            end
            
            %save to properties
            app.word = word;
            
            %plot audio after cut
            figure;
            for k = 1:vsl
                subplot(3,2,k);
                plot(word{k},'k');
                title_speech=sprintf('WORD NUMBER %d',k);
                title(title_speech);
            end
            
            % timeElapsed = toc;
            % disp(timeElapsed);
            
        end

        % Button pushed function: PasswordDetect
        function PasswordDetectButtonPushed(app, event)
            %% Train codebook password 0-9
            n = app.NumberofSpearker;
            k = app.centroid;                         % number of centroids required
            %             password = [0 3 7 2 5 6];
            
            %k=k*2;
            for i = 1:n                  % train a VQ codebook for each speaker
                file = sprintf('%ss%d.wav',app.trainpasswordpath, i);
                %sprintf(file);
                %                     app.TextArea.Value = [string(app.TextArea.Value);"Create codebook for each audio in train folder:";file];
                
                [s, fs] = audioread(file);
                s(s==0)=[];
                s=s/max(abs(s));
                %                     s = pre_emph(s);
                v = mymfcc(s, fs);            % Compute MFCC'
                code{i} = vqlbg(v, k,2);      % Train VQ codebook
            end
            
            %     app.codebookpass = code;
            %% Detect password
            
            %     code = app.codebookpass;
            fs =app.Fs;
            word = app.word;
            
            %eliminate zero elements
            
            for j = 1:length(word)
                word{j}(word{j}==0)= []
            end
            
            %caculate MFCC for each voiceseg
            for ll = 1:length(word)
                v = mymfcc(word{ll},fs);
                
                distmin = inf;
                k1 = 0;
                
                for l = 1:length(code)      % each trained codebook, compute distortion
                    d = disteu(v, code{l},2);
                    dist = sum(min(d,[],2)) / size(d,1);
                    
                    %                 app.distance2excel(l) = dist;
                    
                    file = sprintf('%ss%d.wav', app.trainpasswordpath, l);
                    %                     app.TextArea.Value = [string(app.TextArea.Value);file;"Distance:";num2str(dist)];
                    
                    if dist < distmin
                        distmin = dist;
                        k1 = l;
                    end
                end
                
                %          message = sprintf("Matches with %d",k2);
                %          disp(k1);
                k2(ll) = k1-1;
                message = sprintf("Matches with %d",(k1-1));
                app.TextArea.Value = [string(app.TextArea.Value);message];
                
            end
            
            %      So sanh password
            
            [count,flag] = checkpass(k2,app.password);
            
            if (flag)
                app.TextArea.Value = [string(app.TextArea.Value);'Dung mat khau'];
                app.Lamp.Color     = [0 1 0]; %Green
                %                        app.Label_Lamp.Text
            else
                app.TextArea.Value = [string(app.TextArea.Value);'Sai mat khau'];
                app.Lamp.Color     = [1 0 0]; %Red
            end


        end

        % Callback function
        function PasswordEditFieldValueChanged(app, event)
            value = app.PasswordEditField.Value;
            app.password = num2str(value) - '0';
            
        end

        % Callback function
        function PasswordEditFieldValueChanged2(app, event)
            value = app.PasswordEditField.Value;
            
            app.password = num2str(value) - '0';
            %             app.TextArea.Value = [string(app.TextArea.Value);app.password];
        end

        % Data changed function: PasswordHTML
        function PasswordHTMLDataChanged(app, event)
            app.password = app.PasswordHTML.Data;
%             app.Label_2.Text = app.password;
            app.password = num2str(app.password) - '0';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Original'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.RecordButton = uibutton(app.ControlPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = {'Record'; ''};
            app.PlotButton = uibutton(app.ControlPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SpectrumButton = uibutton(app.ControlPanel, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Text = {'Spectrum'; ''};
            app.Load = uibutton(app.ControlPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.MFCC = uibutton(app.ControlPanel, 'push');
            app.MFCC.ButtonPushedFcn = createCallbackFcn(app, @MFCCButtonPushed, true);
            app.MFCC.Text = {'MFCC'; ''};
            app.PreemphCheckBox = uicheckbox(app.ControlPanel);
            app.PreemphCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreemphCheckBoxValueChanged, true);
            app.PreemphCheckBox.Text = {'Preemph'; ''};
            app.ClearButton = uibutton(app.ControlPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Endcut = uibutton(app.ControlPanel, 'push');
            app.Endcut.ButtonPushedFcn = createCallbackFcn(app, @EndcutButtonPushed, true);
            app.Endcut.Text = 'Endpoint Detection';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Hello World'; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo BK_vien trang (1).png';
            app.SpeakerIDEditFieldLabel = uilabel(app.UIFigure);
            app.SpeakerIDEditFieldLabel.Text = {'Speaker ID'; ''};
            app.SpeakerIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeakerIDEditField.ValueChangedFcn = createCallbackFcn(app, @SpeakerIDEditFieldValueChanged, true);
            app.PlayBackPanel = uipanel(app.UIFigure);
            app.PlayBackPanel.Title = 'Play Back';
            app.Sound = uibutton(app.PlayBackPanel, 'push');
            app.Sound.ButtonPushedFcn = createCallbackFcn(app, @SoundButtonPushed, true);
            app.Sound.Text = 'Sound';
            app.Excel = uibutton(app.PlayBackPanel, 'push');
            app.Excel.ButtonPushedFcn = createCallbackFcn(app, @ExcelButtonPushed, true);
            app.Excel.Text = 'Excel';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Speaker ID'; ''};
            app.IdentificationPanel = uipanel(app.UIFigure);
            app.IdentificationPanel.Title = 'Identification';
            app.Train = uibutton(app.IdentificationPanel, 'push');
            app.Train.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.Train.Text = 'Train';
            app.MultiTest = uibutton(app.IdentificationPanel, 'push');
            app.MultiTest.ButtonPushedFcn = createCallbackFcn(app, @MultiTestButtonPushed, true);
            app.MultiTest.Text = 'Multiple Test';
            app.Save = uibutton(app.IdentificationPanel, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save';
            app.SingleTest = uibutton(app.IdentificationPanel, 'push');
            app.SingleTest.ButtonPushedFcn = createCallbackFcn(app, @SingleTestButtonPushed, true);
            app.SingleTest.Text = 'Single Test';
            app.PasswordDetect = uibutton(app.IdentificationPanel, 'push');
            app.PasswordDetect.ButtonPushedFcn = createCallbackFcn(app, @PasswordDetectButtonPushed, true);
            app.PasswordDetect.Text = 'Password';
            app.CentroidEditFieldLabel = uilabel(app.UIFigure);
            app.CentroidEditFieldLabel.Text = 'Centroid';
            app.CentroidEditField = uieditfield(app.UIFigure, 'numeric');
            app.CentroidEditField.ValueChangedFcn = createCallbackFcn(app, @CentroidEditFieldValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {''; ''};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.NofSpeakerEditFieldLabel = uilabel(app.UIFigure);
            app.NofSpeakerEditFieldLabel.Text = 'N of Speaker';
            app.NofSpeakerEditField = uieditfield(app.UIFigure, 'numeric');
            app.NofSpeakerEditField.ValueChangedFcn = createCallbackFcn(app, @NofSpeakerEditFieldValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Label_Lamp = uilabel(app.UIFigure);
            app.Label_Lamp.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.PasswordHTML = uihtml(app.Panel);
            app.PasswordHTML.HTMLSource = 'passwordEdit.html';
            app.PasswordHTML.DataChangedFcn = createCallbackFcn(app, @PasswordHTMLDataChanged, true);
            app.PasswordHTML.Data = 'pass';
            app.password_label = uilabel(app.UIFigure);
            app.password_label.Text = 'Password';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Appdesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end